classdef outliers_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes_img     matlab.ui.control.UIAxes
        UIAxes_signal  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgs
        values
        x
        selected
        line
        points
        points_red
        point_selected
    end
    
    properties (Access = public)
        done % Description
        bin_outliers
    end
    
    methods (Access = private)
        
        
        function redraw(app)

            delete(app.point_selected)

            if app.bin_outliers(app.selected)
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'ro');
            else
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'bo');
            end

          
            hold(app.UIAxes_img, 'off')
            imshow(app.imgs(:,:,app.selected),'Parent',app.UIAxes_img);
            if app.bin_outliers(app.selected)
                title(app.UIAxes_img,[num2str(app.selected) '/' num2str(length(app.values))],Color='r')
            else
                title(app.UIAxes_img,[num2str(app.selected) '/' num2str(length(app.values))],Color='k')
            end


        end
        
        function redraw_change(app)
            try 
                delete(app.points)
            end
            try 
                delete(app.points_red)
            end
            try 
                delete(app.point_selected)
            end


            app.points = plot(app.UIAxes_signal,app.x,app.values,'b.');
            app.points_red = plot(app.UIAxes_signal,app.x(app.bin_outliers),app.values(app.bin_outliers),'r.',MarkerSize=8);
            if app.bin_outliers(app.selected)
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'ro');
            else
                app.point_selected = plot(app.UIAxes_signal,app.x(app.selected),app.values(app.selected),'bo');
            end

        end
    end
    
    methods (Access = public)
        
        function closeApp(app)
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, imgs, values, bin_outliers, name)
            app.done = 0;
            app.imgs = imgs;
            app.values = values;
            app.bin_outliers = bin_outliers;

           
            app.x = 1:length(values);

            app.selected = 1;

            hold(app.UIAxes_img, 'off')
            imshow(app.imgs(:,:,app.selected),'Parent',app.UIAxes_img);
            title(app.UIAxes_img,[num2str(app.selected) '/' num2str(length(app.values))])


            hold(app.UIAxes_signal, 'on')
            title(app.UIAxes_signal,name)
            app.line = plot(app.UIAxes_signal,app.x,app.values,'b-');
            app.redraw_change()


%      exportapp(app.UIFigure,[default_name(1:end-4) '_example.png'])

        end

        % Button down function: UIAxes_signal
        function UIAxes_signalButtonDown(app, event)
            
        end

        % Callback function: UIAxes_img, UIFigure
        function UIFigureWindowButtonDown(app, event)
            try 
                if isequal(event.Source.CurrentObject.Parent,app.UIAxes_signal)
                    object_x = event.Source.CurrentObject.Parent;
                else
                    if isequal(event.Source.CurrentObject,app.UIAxes_signal)
                        object_x = event.Source.CurrentObject ;
                    else
                        return
                    end
                end
            catch
                return
            end
            

%              if strcmp(app.UIFigure.SelectionType,'normal')
%                  tmp =2;
%              elseif strcmp(app.UIFigure.SelectionType,'alt')
%                  tmp = 3;
%              elseif strcmp(app.UIFigure.SelectionType,'extend')
%                  tmp = 1;
%              end

                
             position=object_x.CurrentPoint;

             position=[position(1,1),position(1,2)];


             ylim = object_x.YLim;
             xlim = object_x.XLim;

             x_range = xlim(2) - xlim(1);
             y_range = ylim(2) - ylim(1);

             w = (x_range / y_range) / 2;

             
             dists = (position(1) - app.x).^2 + (w *(position(2) - app.values)).^2;
             
             ind = find(dists == min(dists),1);
             

             if ind <= 0
                 ind = 1;
             end
             if ind > length(app.values)
                 ind = length(app.values);
             end

            
             if strcmp(app.UIFigure.SelectionType,'normal')
                 app.selected = ind;
                 app.redraw();
             elseif strcmp(app.UIFigure.SelectionType,'alt')
                 app.bin_outliers(ind) = ~app.bin_outliers(ind);
                 app.redraw_change();
             end


        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'leftarrow')
                app.selected = app.selected - 1;
            end
            if strcmp(key,'rightarrow')
                app.selected = app.selected + 1;
            end
            if strcmp(key,'leftarrow')||strcmp(key,'rightarrow')
                if app.selected <= 0
                    app.selected = 1;
                end
                if app.selected > length(app.values)
                    app.selected = length(app.values);
                end
                app.redraw();
            end
            if strcmp(key,'space') || strcmp(key,'uparrow') || strcmp(key,'downarrow') 
                app.bin_outliers(app.selected) = ~app.bin_outliers(app.selected);
                app.redraw_change();
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             delete(app)
            app.done = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes_signal = uiaxes(app.UIFigure);
            title(app.UIAxes_signal, 'Title')
            xlabel(app.UIAxes_signal, 'X')
            ylabel(app.UIAxes_signal, 'Y')
            zlabel(app.UIAxes_signal, 'Z')
            app.UIAxes_signal.ButtonDownFcn = createCallbackFcn(app, @UIAxes_signalButtonDown, true);
            app.UIAxes_img = uiaxes(app.UIFigure);
            title(app.UIAxes_img, 'Title')
            xlabel(app.UIAxes_img, 'X')
            ylabel(app.UIAxes_img, 'Y')
            zlabel(app.UIAxes_img, 'Z')
            app.UIAxes_img.ButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outliers_analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end